classdef DataAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotPlotsButton             matlab.ui.control.Button
        LoadDataFileButton          matlab.ui.control.Button
        SaveDatatoExcelFileButton   matlab.ui.control.Button
        ExtremeSelection            matlab.ui.container.ButtonGroup
        LowestButton                matlab.ui.control.RadioButton
        HighestButton               matlab.ui.control.RadioButton
        ListofExtremesasChosenAboveLabel  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        SelectStartingandEndingYearsLabel  matlab.ui.control.Label
        EndingYearEditField         matlab.ui.control.NumericEditField
        EndingYearEditFieldLabel    matlab.ui.control.Label
        StartingYearEditField       matlab.ui.control.NumericEditField
        StartingYearEditFieldLabel  matlab.ui.control.Label
        TmaxOptions                 matlab.ui.container.ButtonGroup
        RawTempButton               matlab.ui.control.RadioButton
        thistemperaturevalueEditFieldLabel  matlab.ui.control.Label
        OverUnderTempButton         matlab.ui.control.RadioButton
        RawAvgWithSTDDevButton      matlab.ui.control.RadioButton
        TempValue                   matlab.ui.control.NumericEditField
        PercipSnowOptions           matlab.ui.container.ButtonGroup
        RawPercipButton             matlab.ui.control.RadioButton
        PercipTempValue             matlab.ui.control.NumericEditField
        LabelforTempValueDoNotEdit  matlab.ui.control.Label
        OverUnderPercipButton       matlab.ui.control.RadioButton
        RawAvgPercipButton          matlab.ui.control.RadioButton
        YearlyTotalsButton          matlab.ui.control.RadioButton
        Title                       matlab.ui.control.Label
        BottomAxes                  matlab.ui.control.UIAxes
        TopAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Data
        YearStartEnd
        WeatherData
    end

    methods (Access = private)
        function [TempAverage, stDev] = AverageAndSTDYearly(app, data, year)
            if isKey(app.YearStartEnd, year)
                startRow = app.YearStartEnd(year);
                startRow = cell2mat(startRow);
                startRow = startRow(1);
                endRow = app.YearStartEnd(year);
                endRow = cell2mat(endRow);
                endRow = endRow(2);
                temperatures = data(startRow:endRow);
                TempAverage = mean(temperatures, 'omitnan');
                stDev = std(temperatures, 'omitnan');
            else
                %this is to account if the year is even in the dataset
                %                 TempAverage = 0;
                %                 stDev = 0;
            end
        end

        %straightforward function, simple forloop that used a counter to amount
        %number of times the particular value in data was over or under the
        %selected value from the user.

        %returns 2x1 vector [overamount underamount]
        function [overCounter, underCounter] = DaysOverUnderVal(app, data, userInput)
            overCounter = 0;
            underCounter = 0;
            for i = 1:length(data)
                if data(i) > userInput
                    overCounter = overCounter + 1;
                elseif(data(i) <= userInput)
                    underCounter = underCounter + 1;
                end
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataFileButton
        function LoadDataFileButtonPushed(app, event)
            %function for when the 'Load Data from file path above' button is
            %pressed
            app.WeatherData = load("CLEWeatherData.mat", "WeatherData");
            app.WeatherData = app.WeatherData.WeatherData;
            msgbox('CLEWeatherData.mat has been loaded in')


            app.YearStartEnd = dictionary();
            for year = 1997:2017
                StartRow = 1;
                EndRow = 1;
                FirstFoundMatch = false;
                while  EndRow+1 < length(app.WeatherData.DATE)
                    if app.WeatherData.DATE(EndRow).Year == year
                        if ~FirstFoundMatch
                            StartRow = EndRow;
                            FirstFoundMatch = true;

                        end
                    elseif FirstFoundMatch && ...
                            ~(app.WeatherData.DATE(EndRow).Year == year)
                        break
                    end

                    EndRow = EndRow+1;
                end
                app.YearStartEnd(year) = {[StartRow, EndRow]};
            end

        end

        % Button pushed function: PlotPlotsButton
        function PlotPlotsButtonPushed(app, event)
            % Clear whatever's there at the moment
            cla(app.TopAxes)
            cla(app.BottomAxes)


            % Make sure inputs are valid
            startYear = app.StartingYearEditField.Value;
            endYear = app.EndingYearEditField.Value;
            if ~isKey(app.YearStartEnd, startYear) || ~isKey(app.YearStartEnd, endYear)
                uiconfirm(app.UIFigure, ...
                    "Incorrect Year inputted in either Start or " + ...
                    "End Box, please select another year.", ...
                    'Incorrect Year', ...
                    'Options', {'OK'}, ...
                    'Icon', 'warning');
                return
            end

            % Plot Plots according to settings
            startRow = app.YearStartEnd(startYear);
            startRow = cell2mat(startRow);
            startRow = startRow(1);
            endRow = app.YearStartEnd(endYear);
            endRow = cell2mat(endRow);
            endRow = endRow(2);
            % Temperature Plots
            if app.RawTempButton.Value
                plot(app.TopAxes, ...
                    app.WeatherData.DATE(startRow:endRow), ...
                    app.WeatherData.TMAX(startRow:endRow), "Color", "r")
                title(app.TopAxes, "Raw Temperature Values")
                ylabel(app.TopAxes, "Temperature")
                xlabel(app.TopAxes, "Day")
                app.Data.TEMP = [string(app.WeatherData.DATE(startRow:endRow)), ...
                    app.WeatherData.TMAX(startRow:endRow)];
                drawnow();
            elseif app.RawAvgWithSTDDevButton.Value
                years = [];
                avgs = [];
                stdDevs = [];
                for year = startYear:endYear
                    years = cat(2, years, year);
                    [average, stdDev] = app.AverageAndSTDYearly( ...
                        app.WeatherData.TMAX, ...
                        year);
                    avgs = cat(2, avgs, average);
                    stdDevs = cat(2, stdDevs, stdDev);
                end
                errorbar(app.TopAxes, years, avgs, stdDevs, "Color", "r")
                title(app.TopAxes, "Averages with Standard Deviation")
                xlabel(app.TopAxes, "Year")
                ylabel(app.TopAxes, "Average")
                app.Data.TEMP = [years', avgs', stdDevs'];
                drawnow();
            elseif app.OverUnderTempButton.Value
                X = [];
                counters = [];
                for year = startYear:endYear
                    RowNum = cell2mat(app.YearStartEnd(year));
                    startRow = RowNum(1);
                    endRow = RowNum(2);
                    [overCounter, underCounter] = ...
                        app.DaysOverUnderVal( ...
                        app.WeatherData.TMAX(startRow:endRow), ...
                        app.TempValue.Value);
                    X = cat(2, X, ("Over - " + year), ...
                        ("Under - " + year));
                    counters = cat(2, counters, overCounter, underCounter);
                end
                bar(app.TopAxes, categorical(X), counters, "r")
                title(app.TopAxes, "Over or Under")
                ylabel(app.TopAxes, "Number of Occurances")
                app.Data.TEMP = [X, counters];
            end

            % Percipitation Plots

            if app.RawPercipButton.Value
                hold(app.BottomAxes, "on")
                plot(app.BottomAxes, ...
                    app.WeatherData.DATE(startRow:endRow), ...
                    app.WeatherData.PRCP(startRow:endRow), "Color", "g")
                plot(app.BottomAxes, ...
                    app.WeatherData.DATE(startRow:endRow), ...
                    app.WeatherData.SNOW(startRow:endRow), "Color", "b")
                title(app.BottomAxes, "Raw Percip and Snowfall Values")
                ylabel(app.BottomAxes, "Fall (in)")
                xlabel(app.BottomAxes, "Day")
                drawnow();
                hold(app.BottomAxes, "off")
                app.Data.PRCP = [string(app.WeatherData.DATE(startRow:endRow)), ...
                    app.WeatherData.PRCP(startRow:endRow)];
                app.Data.SNOW = [string(app.WeatherData.DATE(startRow:endRow)), ...
                    app.WeatherData.SNOW(startRow:endRow)];
            elseif app.YearlyTotalsButton.Value
                hold(app.BottomAxes, "on")
                % Check if a categorical plot has been used before on these
                % axes, if so get rid of the past mistakes.
                if isprop(app.BottomAxes.XAxis, 'Categories')
                    app.BottomAxes.XAxis.Categories = categorical();
                end
                years = [];
                totals = [];
                for year = startYear:endYear
                    years = cat(2, years, year);
                    if isKey(app.YearStartEnd, year)
                        RowNum = cell2mat(app.YearStartEnd(year));
                        startRow = RowNum(1);
                        endRow = RowNum(2);
                        totals = cat(1, totals, ...
                            [sum(app.WeatherData.PRCP(startRow:endRow), "omitnan"), ...
                            sum(app.WeatherData.SNOW(startRow:endRow), "omitnan")]);
                    end
                end
                X = categorical(years);
                bar(app.BottomAxes, X, totals)
                title(app.BottomAxes, "Yearly Totals")
                ylabel(app.BottomAxes, "Total per year")
                hold(app.BottomAxes, "off")
                app.Data.PRCP = [years', totals(:, 1)];
                app.Data.SNOW = [years', totals(:, 2)];
            elseif app.RawAvgPercipButton.Value
                hold(app.BottomAxes, "on")
                for i = 1:2
                    if i == 1
                        data = app.WeatherData.PRCP;
                        color = "g";
                    else
                        data = app.WeatherData.SNOW;
                        color = "b";
                    end
                    years = [];
                    avgs = [];
                    stdDevs = [];
                    for year = startYear:endYear
                        years = cat(2, years, year);
                        [average, stdDev] = app.AverageAndSTDYearly(data, year);
                        avgs = cat(2, avgs, average);
                        stdDevs = cat(2, stdDevs, stdDev);
                    end

                    errorbar(app.BottomAxes, years, avgs, stdDevs, ...
                        "Color", color)
                    title(app.BottomAxes, "Averages with Standard Deviation")
                    xlabel(app.BottomAxes, "Year")
                    ylabel(app.BottomAxes, "Average")
                    drawnow();
                    if i == 1
                        app.Data.PRCP = [years', avgs', stdDevs'];
                    else
                        app.Data.SNOW = [years', avgs', stdDevs'];
                    end
                end
            elseif app.OverUnderPercipButton.Value
                hold(app.BottomAxes, "on")
                % Check if a categorical plot has been used before on these
                % axes, if so get rid of the past mistakes.
                if isprop(app.BottomAxes.XAxis, 'Categories')
                    app.BottomAxes.XAxis.Categories = categorical();
                end
                X = [];
                counters = [];
                for year = startYear:endYear
                    RowNum = cell2mat(app.YearStartEnd(year));
                    startRow = RowNum(1);
                    endRow = RowNum(2);
                    [overCounter, underCounter] = ...
                        app.DaysOverUnderVal( ...
                        app.WeatherData.PRCP(startRow:endRow), ...
                        app.PercipTempValue.Value);
                    X = cat(2, X, ("Over - " + year), ...
                        ("Under - " + year));
                    counters = cat(2, counters, overCounter, underCounter);
                end
                bar(app.BottomAxes, categorical(X), counters, "g")
                title(app.BottomAxes, "Over or Under")
                ylabel(app.BottomAxes, "Number of Occurances")
                app.Data.PRCP = [X, counters];
                % Snow Process
                X = [];
                counters = [];
                for year = startYear:endYear
                    RowNum = cell2mat(app.YearStartEnd(year));
                    startRow = RowNum(1);
                    endRow = RowNum(2);
                    [overCounter, underCounter] = ...
                        app.DaysOverUnderVal( ...
                        app.WeatherData.SNOW(startRow:endRow), ...
                        app.PercipTempValue.Value);
                    X = cat(2, X, ("Over - " + year), ...
                        ("Under - " + year));
                    counters = cat(2, counters, overCounter, underCounter);
                end
                bar(app.BottomAxes, categorical(X), counters, "b")
                title(app.BottomAxes, "Over or Under")
                ylabel(app.BottomAxes, "Number of Occurances")
                hold(app.BottomAxes, "off")
                app.Data.SNOW = [X, counters];
            end
            hold(app.BottomAxes,"off");

            % Calculate and fill out extremes table

            topYearsTemp = [];
            topValuesTemp = [];
            topYearsPercip = [];
            topValuesPercip = [];
            topYearsSnow = [];
            topValuesSnow= [];

            % Find the extreme years based on average Tmax, total precipitation, and snowfall
            if app.HighestButton.Value
                for year = startYear:endYear
                    startRow = cell2mat(app.YearStartEnd(year));
                    startRow = startRow(1);
                    endRow = cell2mat(app.YearStartEnd(year));
                    endRow = endRow(2);
                    Precipitation = app.WeatherData.PRCP(startRow:endRow);
                    SnowFall = app.WeatherData.SNOW(startRow:endRow);
                    TMax = app.WeatherData.TMAX(startRow:endRow);
                    % Calculate mean, psum, and ssum
                    Tmean = mean(TMax, 'omitnan');
                    Psum = sum(Precipitation, 'omitnan');
                    Ssum = sum(SnowFall, 'omitnan');
                    % append to each list then sort both arrays accordingly
                    topYearsTemp = cat(2, topYearsTemp, year);
                    topValuesTemp = cat(2, topValuesTemp, Tmean);
                    [topValuesTemp, I] = sort(topValuesTemp, 'descend');
                    topYearsTemp = topYearsTemp(I);

                    topYearsPercip = cat(2, topYearsPercip, year);
                    topValuesPercip = cat(2, topValuesPercip, Psum);
                    [topValuesPercip, I] = sort(topValuesPercip, 'descend');
                    topYearsPercip = topYearsPercip(I);

                    topYearsSnow = cat(2, topYearsSnow, year);
                    topValuesSnow= cat(2, topValuesSnow, Ssum);
                    [topValuesSnow, I] = sort(topValuesSnow, 'descend');
                    topYearsSnow = topYearsSnow(I);
                end
            else %this means highest button is not selected so lowest button must be selected
                for year = startYear:endYear
                    startRow = cell2mat(app.YearStartEnd(year));
                    startRow = startRow(1);
                    endRow = cell2mat(app.YearStartEnd(year));
                    endRow = endRow(2);
                    Precipitation = app.WeatherData.PRCP(startRow:endRow);
                    SnowFall = app.WeatherData.SNOW(startRow:endRow);
                    TMax = app.WeatherData.TMAX(startRow:endRow);
                    % Calculate mean, psum, and ssum
                    Tmean = mean(TMax, 'omitnan');
                    Psum = sum(Precipitation, 'omitnan');
                    Ssum = sum(SnowFall, 'omitnan');
                    % append to each list then sort both arrays accordingly
                    topYearsTemp = cat(2, topYearsTemp, year);
                    topValuesTemp = cat(2, topValuesTemp, Tmean);
                    [topValuesTemp, I] = sort(topValuesTemp, 'ascend');
                    topYearsTemp = topYearsTemp(I);

                    topYearsPercip = cat(2, topYearsPercip, year);
                    topValuesPercip = cat(2, topValuesPercip, Psum);
                    [topValuesPercip, I] = sort(topValuesPercip, 'ascend');
                    topYearsPercip = topYearsPercip(I);

                    topYearsSnow = cat(2, topYearsSnow, year);
                    topValuesSnow= cat(2, topValuesSnow, Ssum);
                    [topValuesSnow, I] = sort(topValuesSnow, 'ascend');
                    topYearsSnow = topYearsSnow(I);
                end
            end

            titles = ["Temperature"; "Precipitation"; "Snowfall"];
            % ensure there are 5 datapoints, if not, just return what's
            % there
            if length(topYearsTemp) >= 5
            dates = [string(topYearsTemp(1:5)'), ...
                string(topYearsPercip(1:5)'), ...
                string(topYearsSnow(1:5)')];
            else
                dates = [string(topYearsTemp(1:length(topYearsTemp))'), ...
                string(topYearsPercip(1:length(topYearsPercip))'), ...
                string(topYearsSnow(1:length(topYearsSnow))')];
            end
            ExtremesTableData = table(titles, dates');

            %creates the table that lists the dates of the 5 extremes found in the code
            %above.

            app.UITable.Data = ExtremesTableData;
            %displays the table set above
        end

        % Button pushed function: SaveDatatoExcelFileButton
        function SaveDatatoExcelFileButtonPushed(app, event)
            % Create a file name for the XLS file using the current date and time
            CurrentTime = datetime('now', 'Format', 'MM dd yy hh mm');
            %uses datetime function to set the current date and time to the
            %variable CurrentTime and the rest is just specs for the function.
            % specs display month, date, year, then hour/minute am or pm.

            FileName = "WeatherData_" + string(CurrentTime)+ ".xlsx";
            %Sets the variable for file name to 'WeatherData_' combined with
            %the character array of the current time varibale, made from using
            % char fucntion and ends the file
            %name with .xlsx which is the data file type.

            writematrix(app.Data.TEMP, FileName, "Sheet", "Temperature");
            writematrix(app.Data.PRCP, FileName, "Sheet", "Percipitation");
            writematrix(app.Data.SNOW, FileName, "Sheet", "Snow");
            % uses writematrix function to make a file of the data in the first
            % value 'app.Data' and names the file based off of the second value
            % entered, in this case the 'FileName' variable.

            uiconfirm(app.UIFigure, sprintf('Data has been saved to file "%s"', ...
                FileName),'File Saved', 'Options', {'OK'}, 'Icon', 'info');
            %uses same uiconfirm function as used for load data button to
            %display a confirmable message that displays in the GUI field,
            %using sprintf to display text and value stored in FileName
            %variable to show name that file was made unnder.
            %'Options' sets options to only ok button, rather than defualt ok
            %and cancel button, 'icon' changes icon to info sysmbol.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TopAxes = uiaxes(app.UIFigure);
            title(app.TopAxes, 'Title')
            xlabel(app.TopAxes, 'X')
            ylabel(app.TopAxes, 'Y')
            zlabel(app.TopAxes, 'Z')
            app.BottomAxes = uiaxes(app.UIFigure);
            title(app.BottomAxes, 'Title')
            xlabel(app.BottomAxes, 'X')
            ylabel(app.BottomAxes, 'Y')
            zlabel(app.BottomAxes, 'Z')
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Meteorological Data Analysis ';
            app.PercipSnowOptions = uibuttongroup(app.UIFigure);
            app.PercipSnowOptions.Title = 'Choose plot type for precipitation and snowfall';
            app.YearlyTotalsButton = uiradiobutton(app.PercipSnowOptions);
            app.YearlyTotalsButton.Text = {'Yearly Totals'; ''};
            app.YearlyTotalsButton.Value = true;
            app.RawAvgPercipButton = uiradiobutton(app.PercipSnowOptions);
            app.RawAvgPercipButton.Text = {'Raw Yearly Average (with ± Standard Deviation)'; ''};
            app.OverUnderPercipButton = uiradiobutton(app.PercipSnowOptions);
            app.OverUnderPercipButton.Text = {'number of days per year over or under'; ''};
            app.LabelforTempValueDoNotEdit = uilabel(app.PercipSnowOptions);
            app.LabelforTempValueDoNotEdit.Text = 'this temperature value ( )';
            app.PercipTempValue = uieditfield(app.PercipSnowOptions, 'numeric');
            app.RawPercipButton = uiradiobutton(app.PercipSnowOptions);
            app.RawPercipButton.Text = 'Raw Percipitation/Snowfall Data';
            app.TmaxOptions = uibuttongroup(app.UIFigure);
            app.TmaxOptions.Title = 'Choose plot type for maximum temperature (Tmax)';
            app.TempValue = uieditfield(app.TmaxOptions, 'numeric');
            app.RawAvgWithSTDDevButton = uiradiobutton(app.TmaxOptions);
            app.RawAvgWithSTDDevButton.Text = {'Raw Yearly Average (with ± Standard Deviation)'; ''};
            app.RawAvgWithSTDDevButton.Value = true;
            app.OverUnderTempButton = uiradiobutton(app.TmaxOptions);
            app.OverUnderTempButton.Text = {'number of days per year over or under'; ''};
            app.thistemperaturevalueEditFieldLabel = uilabel(app.TmaxOptions);
            app.thistemperaturevalueEditFieldLabel.Text = 'this temperature value ( )';
            app.RawTempButton = uiradiobutton(app.TmaxOptions);
            app.RawTempButton.Text = 'Raw Temperature Data';
            app.StartingYearEditFieldLabel = uilabel(app.UIFigure);
            app.StartingYearEditFieldLabel.Text = 'Starting Year';
            app.StartingYearEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndingYearEditFieldLabel = uilabel(app.UIFigure);
            app.EndingYearEditFieldLabel.Text = 'Ending Year';
            app.EndingYearEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectStartingandEndingYearsLabel = uilabel(app.UIFigure);
            app.SelectStartingandEndingYearsLabel.Text = {'Select Starting and Ending Years'; ''};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Number'; 'Value'};
            app.UITable.RowName = {};
            app.ListofExtremesasChosenAboveLabel = uilabel(app.UIFigure);
            app.ListofExtremesasChosenAboveLabel.Text = 'List of Extremes as Chosen Above';
            app.ExtremeSelection = uibuttongroup(app.UIFigure);
            app.ExtremeSelection.Title = 'What extremes would you like to see?';
            app.HighestButton = uiradiobutton(app.ExtremeSelection);
            app.HighestButton.Text = 'Highest';
            app.HighestButton.Value = true;
            app.LowestButton = uiradiobutton(app.ExtremeSelection);
            app.LowestButton.Text = 'Lowest';
            app.SaveDatatoExcelFileButton = uibutton(app.UIFigure, 'push');
            app.SaveDatatoExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDatatoExcelFileButtonPushed, true);
            app.SaveDatatoExcelFileButton.Text = {'Save Data'; 'to Excel File'};
            app.LoadDataFileButton = uibutton(app.UIFigure, 'push');
            app.LoadDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataFileButtonPushed, true);
            app.LoadDataFileButton.Text = 'Load Data File';
            app.PlotPlotsButton = uibutton(app.UIFigure, 'push');
            app.PlotPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPlotsButtonPushed, true);
            app.PlotPlotsButton.Text = 'Plot All Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAnalysisApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end