classdef ENGR131_23S_Lab7_taz24 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        TotalRunTimeGauge        matlab.ui.control.LinearGauge
        TotalRunTimeGaugeLabel   matlab.ui.control.Label
        BurritoSwitch            matlab.ui.control.ToggleSwitch
        BurritoSwitchLabel       matlab.ui.control.Label
        PizzaSwitch              matlab.ui.control.ToggleSwitch
        PizzaSwitchLabel         matlab.ui.control.Label
        DoorSwitch               matlab.ui.control.Switch
        DoorSwitchLabel          matlab.ui.control.Label
        StatusLamp               matlab.ui.control.Lamp
        ReadyLabel               matlab.ui.control.Label
        RunButton                matlab.ui.control.Button
        TimeRemainingGauge       matlab.ui.control.LinearGauge
        TimeRemainingGaugeLabel  matlab.ui.control.Label
        CookTimeKnob             matlab.ui.control.DiscreteKnob
        CookTimeKnobLabel        matlab.ui.control.Label
        MicrowavePlot            matlab.ui.control.UIAxes
        RightPanel               matlab.ui.container.Panel
        ButtonGroup              matlab.ui.container.ButtonGroup
        OtherButton              matlab.ui.control.RadioButton
        BurritoButton            matlab.ui.control.RadioButton
        PizzaButton              matlab.ui.control.RadioButton
        PlotButton               matlab.ui.control.Button
        PlotColorDropDown        matlab.ui.control.DropDown
        PlotColorLabel           matlab.ui.control.Label
        OccurancesPlot           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        TotalCookTime % Description
        FoodCooked % Description
    end

    methods (Access = private)
        function results = plotLineColor(app, color)
            results = color + "--";
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DoorSwitch.Value = 'Open';
            app.StatusLamp.Color = 'red';
            app.TotalCookTime = 0;
            app.FoodCooked = 0;

        end

        % Value changed function: DoorSwitch
        function DoorSwitchValueChanged(app, event)
            value = app.DoorSwitch.Value;
            % Door open - white, door closed - black
            if (isequal(value, 'Open'))
                app.MicrowavePlot.set("Color", "white")
            else
                app.MicrowavePlot.set("Color", "black")
            end

        end

        % Value changed function: PizzaSwitch
        function PizzaSwitchValueChanged(app, event)
            value = app.PizzaSwitch.Value;
            % If switch is on, make the cook time low, if not, make it off
            if isequal(value, "On")
                app.CookTimeKnob.Value = 'Low';
            else
                app.CookTimeKnob.Value = 'Off';
            end
        end

        % Value changed function: BurritoSwitch
        function BurritoSwitchValueChanged(app, event)
            value = app.BurritoSwitch.Value;
            % If switch is on, make the cook time med, if not, make it off
            if isequal(value, "On")
                app.CookTimeKnob.Value = 'Med';
            else
                app.CookTimeKnob.Value = 'Off';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Waiting Segment - Wait for door to be closed and cook time to
            % be non-zero
            while isequal(app.DoorSwitch.Value, 'Open')
                pause(0.5);
            end
            while isequal(app.CookTimeKnob.Value, 'Off')
                pause(0.5);
            end
            disp("Finished Waiting");
            % Change the Lamp Color
            app.StatusLamp.Color = 'yellow';

            % Cook Segment
            % Check for which switches are on, append the ledger
            % accordingly using a cool one liner and a nor gate
            selectionVector = [isequal(app.PizzaSwitch.Value, 'On');
                isequal(app.BurritoSwitch.Value, 'On');
                not(or(isequal(app.PizzaSwitch.Value, 'On'), ...
                isequal(app.BurritoSwitch.Value, 'On')))];

            if app.FoodCooked == [0]
                app.FoodCooked = selectionVector;
            else
                app.FoodCooked = cat(2, app.FoodCooked, selectionVector);
            end

            % Check cook time knob value and set cook time accordingly
            if isequal(app.CookTimeKnob.Value, 'Low')
                cookTime = 2;
            elseif isequal(app.CookTimeKnob.Value, 'Med')
                cookTime = 5;
            elseif isequal(app.CookTimeKnob.Value, 'High')
                cookTime = 10;
            end

            initialCookTime = cookTime;
            % Using a timer and a while loop go through the cooking process
            timer = tic();
            disp("Cooking")
            while cookTime > 0
                app.TimeRemainingGauge.Value = cookTime;
                cookTime = cookTime - toc(timer);
                if (cookTime < 0)
                    cookTime = 0;
                    app.TimeRemainingGauge.Value = cookTime;
                end
                % Notable comment because the cool fade was shockingly easy
                app.MicrowavePlot.set("Color", ...
                    [(cookTime/initialCookTime), 0, 0]);
                drawnow();
                pause(0.1);
            end
            % Update total cook time
            app.TotalCookTime = app.TotalCookTime + initialCookTime;
            app.TotalRunTimeGauge.Value = app.TotalCookTime;

            % Reset Segment - Reset all the things according to the lab
            disp("Resetting")
            app.StatusLamp.Color = 'green';
            app.CookTimeKnob.Value = 'Off';
            app.PizzaSwitch.Value = 'Off';
            app.BurritoSwitch.Value = 'Off';
            app.DoorSwitch.Value = 'Open';
            app.MicrowavePlot.set("Color", "white")


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            colorSel = app.PlotColorDropDown.Value;
            LineColor = app.plotLineColor(colorSel);
            arrSize = size(app.FoodCooked, 2);
            % Make a time variable for plotting
            time = linspace(1, arrSize, arrSize);
            % Using the same design as above, plot the first, second or
            % third row of the foodcooked array for the case specified.
            disp(app.FoodCooked);
            if app.PizzaButton.Value
                plot(app.OccurancesPlot, time, app.FoodCooked(1, :), LineColor);
                title(app.OccurancesPlot, "Pizza");
            elseif app.BurritoButton.Value
                plot(app.OccurancesPlot, time, app.FoodCooked(2, :), LineColor);
                title(app.OccurancesPlot, "Burrito");
            elseif app.OtherButton.Value
                plot(app.OccurancesPlot, time, app.FoodCooked(3, :), LineColor);
                title(app.OccurancesPlot, "Other");
            end
            %             app.OccurancesPlot.XLabel = "Total Runs";
            %             app.OccurancesPlot.YLabel = "Occurances";

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {415, 415};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {466, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MicrowavePlot = uiaxes(app.LeftPanel);
            zlabel(app.MicrowavePlot, 'Z')
            app.MicrowavePlot.XLim = [0 1];
            app.MicrowavePlot.XTick = [];
            app.MicrowavePlot.YTick = [];
            app.CookTimeKnobLabel = uilabel(app.LeftPanel);
            app.CookTimeKnobLabel.Text = 'Cook Time';
            app.CookTimeKnob = uiknob(app.LeftPanel, 'discrete');
            app.CookTimeKnob.Items = {'Off', 'Low', 'Med', 'High'};
            app.TimeRemainingGaugeLabel = uilabel(app.LeftPanel);
            app.TimeRemainingGaugeLabel.Text = 'Time Remaining';
            app.TimeRemainingGauge = uigauge(app.LeftPanel, 'linear');
            app.TimeRemainingGauge.Limits = [0 10];
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ReadyLabel = uilabel(app.LeftPanel);
            app.ReadyLabel.Text = 'Status ';
            app.StatusLamp = uilamp(app.LeftPanel);
            app.DoorSwitchLabel = uilabel(app.LeftPanel);
            app.DoorSwitchLabel.Text = 'Door';
            app.DoorSwitch = uiswitch(app.LeftPanel, 'slider');
            app.DoorSwitch.Items = {'Closed', 'Open'};
            app.DoorSwitch.ValueChangedFcn = createCallbackFcn(app, @DoorSwitchValueChanged, true);
            app.DoorSwitch.Value = 'Closed';
            app.PizzaSwitchLabel = uilabel(app.LeftPanel);
            app.PizzaSwitchLabel.Text = 'Pizza';
            app.PizzaSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.PizzaSwitch.ValueChangedFcn = createCallbackFcn(app, @PizzaSwitchValueChanged, true);
            app.BurritoSwitchLabel = uilabel(app.LeftPanel);
            app.BurritoSwitchLabel.Text = 'Burrito';
            app.BurritoSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.BurritoSwitch.ValueChangedFcn = createCallbackFcn(app, @BurritoSwitchValueChanged, true);
            app.TotalRunTimeGaugeLabel = uilabel(app.LeftPanel);
            app.TotalRunTimeGaugeLabel.Text = 'Total Run Time';
            app.TotalRunTimeGauge = uigauge(app.LeftPanel, 'linear');
            app.RightPanel = uipanel(app.GridLayout);
            app.OccurancesPlot = uiaxes(app.RightPanel);
            title(app.OccurancesPlot, 'Other')
            xlabel(app.OccurancesPlot, 'Total Runs')
            ylabel(app.OccurancesPlot, 'Occurences')
            zlabel(app.OccurancesPlot, 'Z')
            app.PlotColorLabel = uilabel(app.RightPanel);
            app.PlotColorLabel.Text = 'Plot Color ';
            app.PlotColorDropDown = uidropdown(app.RightPanel);
            app.PlotColorDropDown.Items = {'Blue', 'Red', 'Green', 'Black'};
            app.PlotColorDropDown.Value = 'Blue';
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ButtonGroup = uibuttongroup(app.RightPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.PizzaButton = uiradiobutton(app.ButtonGroup);
            app.PizzaButton.Text = 'Pizza';
            app.PizzaButton.Value = true;
            app.BurritoButton = uiradiobutton(app.ButtonGroup);
            app.BurritoButton.Text = 'Burrito';
            app.OtherButton = uiradiobutton(app.ButtonGroup);
            app.OtherButton.Text = 'Other';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ENGR131_23S_Lab7_taz24

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end